classdef Final_Project_Mastering_Programming_with_MATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19DataVisualizationDataSourceUIFigure  matlab.ui.Figure
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Global              % Global data
        CountrySelected     % Current selection of country
        DateList            % List of all dates for which data is available
        CumulativeCases     % Cumulative number of Cases for the selected Country/State/Region
        CumulativeDeaths    % Cumulative number of Death for the selected Country/State/Region
        DailyCases          % Daily number of Death for the selected Country/State/Region
        DailyDeaths         % Daily number of Death for the selected Country/State/Region
        AverageCases        % Moving average of Cases   (Cumulative/Daily depending on option selected)
        AverageDeaths       % Moving average Deaths  (Cumulative/Daily depending on option selected)
        AveragingWindow     % width of Averaging window [days]
    end
    
    
    methods (Access = public)
        
        % Compute daily data
        function ComputeDailyData(app)
            app.DailyCases = app.CumulativeCases - circshift(app.CumulativeCases,1);
            app.DailyCases(1) = app.CumulativeCases(1);
            app.DailyCases(app.DailyCases<0) = 0;
            app.DailyDeaths = app.CumulativeDeaths - circshift(app.CumulativeDeaths,1);
            app.DailyDeaths(1) = app.CumulativeDeaths(1);
            app.DailyDeaths(app.DailyDeaths<0) = 0;            
        end
        
        % Compute moving average data (based on past on past (AveragingWindow-1) days)
        function ComputeAverageData(app)
            N = app.AveragingWindow;
            if app.CumulativeButton.Value
                app.AverageCases = movmean(app.CumulativeCases, [N-1 0]);
                app.AverageDeaths = movmean(app.CumulativeDeaths, [N-1 0]);
            end
            if app.DailyButton.Value
                app.ComputeDailyData()
                app.AverageDeaths = movmean(app.DailyDeaths, [N-1 0]);
                app.AverageCases = movmean(app.DailyCases, [N-1 0]);
            end            
        end
        
        % Plot data
        function PlotData(app)
            N = app.AveragingWindow;
            partialDate = app.DateList(N:end);
            cla(app.UIAxes, 'reset')                    % Reset UIAxes
            
            % Plot Data
            if app.CumulativeButton.Value && app.CasesButton.Value
                bar(app.UIAxes, datenum(partialDate), app.AverageCases(N:end))
                
            elseif app.CumulativeButton.Value && app.DeathsButton.Value
                plot(app.UIAxes, datenum(partialDate), app.AverageDeaths(N:end), 'r')
                
            elseif app.DailyButton.Value && app.CasesButton.Value
                bar(app.UIAxes, datenum(partialDate), app.AverageCases(N:end))

            elseif app.DailyButton.Value && app.DeathsButton.Value
                plot(app.UIAxes, datenum(partialDate), app.AverageDeaths(N:end), 'r')
                
            elseif app.DailyButton.Value && app.BothButton.Value
                yyaxis(app.UIAxes, 'left')
                bar(app.UIAxes, datenum(partialDate), app.AverageCases(N:end))
                hold(app.UIAxes, 'on')
                yyaxis(app.UIAxes, 'right')
                plot(app.UIAxes, datenum(partialDate), app.AverageDeaths(N:end), 'r')
                hold(app.UIAxes, 'off')
                
            elseif  app.CumulativeButton.Value && app.BothButton.Value
                yyaxis(app.UIAxes, 'left')
                bar(app.UIAxes, datenum(partialDate), app.AverageCases(N:end))
                hold(app.UIAxes, 'on')
                yyaxis(app.UIAxes, 'right')
                plot(app.UIAxes, datenum(partialDate), app.AverageDeaths(N:end), 'r')
                hold(app.UIAxes, 'off')
            end
            
            % Add Plot title
            str1 = ['in ', app.StateorRegionListBox.Value];
            if strcmpi(str1, 'in All'), str1 =['in ', app.CountryListBox.Value]; end
            if strcmpi(str1, 'in Global'), str1 = 'Globally'; end
            
            str2 = app.DatatoPlotButtonGroup.SelectedObject.Text;
            if strcmpi(str2, 'Both'), str2 = 'Cases and Deaths'; end
            
            str3 = app.OptionButtonGroup.SelectedObject.Text;
            
            if N == 1
                txt = sprintf('%s Number of %s %s', str3, str2, str1);
            else
                txt = sprintf('%s Number of %s %s (%g-day mean)', str3, str2, str1, N);
            end 
            title(app.UIAxes, txt)
            
            % Set Axes Labels and Limits
            StartYear = datenum('01/01/20');
            StopYear = datenum('11/01/20');
            xlim(app.UIAxes,[StartYear StopYear])
            xlim(app.UIAxes,'manual');
            datetick(app.UIAxes, 'x', 'mm/dd', 'keeplimits')
            [app.UIAxes.YAxis.Exponent] = deal(0);
            [app.UIAxes.YAxis.TickLabelFormat] = deal('%.0f');
                      
            % Show grid lines
            grid(app.UIAxes, 'on')
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create Global Data
            app.Global = CovidData; 
            app.Global.ComputeGlobalData;
            
            % Update Country and State/Region Listboxes
            CountryList = arrayfun(@(x)x.Name, app.Global.Country, 'UniformOutput', false);
            CountryList = ['Global', CountryList];
            app.CountryListBox.Items = CountryList;
            app.StateorRegionListBox.Items = {'All'};
            
            % Plot Cumulative Cases and Deaths Globally
            app.DateList = app.Global.DateList;
            app.CumulativeCases = app.Global.CumulativeCases;
            app.CumulativeDeaths = app.Global.CumulativeDeaths;
            app.AveragingWindow = app.AveragedofdaysSlider.Value;
            app.ComputeAverageData()
            app.PlotData()
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            CountryName = app.CountryListBox.Value;
            
            if strcmpi(CountryName, 'Global')
                app.CumulativeCases = app.Global.CumulativeCases;
                app.CumulativeDeaths = app.Global.CumulativeDeaths;
                app.StateorRegionListBox.Items = {'All'};
            else
                app.CountrySelected = findobj(app.Global.Country, 'Name', CountryName);
                StateList = arrayfun(@(x)x.Name, app.CountrySelected.State, 'UniformOutput', false);
                StateList = ['All', StateList];
                app.StateorRegionListBox.Items = StateList;
                app.CumulativeCases = app.CountrySelected.CumulativeCases;
                app.CumulativeDeaths = app.CountrySelected.CumulativeDeaths;
            end
            
            app.ComputeAverageData()
            app.PlotData()
            
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            StateName = app.StateorRegionListBox.Value;
            
            
            if strcmpi(StateName, 'All')
                app.CumulativeCases = app.CountrySelected.CumulativeCases;
                app.CumulativeDeaths = app.CountrySelected.CumulativeDeaths;
            else
                StateSelected = findobj(app.CountrySelected.State, 'Name', StateName);
                app.CumulativeCases = StateSelected.CumulativeCases;
                app.CumulativeDeaths = StateSelected.CumulativeDeaths;
            end
            
            app.ComputeAverageData()
            app.PlotData()
            
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.AveragingWindow = floor(event.Value); % Round the slider value to nearest integer
                                                      % less than or equal to that value
            app.ComputeAverageData()
            app.PlotData()
            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.ComputeAverageData()
            app.PlotData()
            
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.ComputeAverageData()
            app.PlotData()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19DataVisualizationDataSourceUIFigure = uifigure('Visible', 'off');
            app.Covid19DataVisualizationDataSourceUIFigure.Name = 'Covid-19 Data Visualization (Data Source:  Jhons Hopkins University)';
            app.CountryListBoxLabel = uilabel(app.Covid19DataVisualizationDataSourceUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.Covid19DataVisualizationDataSourceUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.Covid19DataVisualizationDataSourceUIFigure);
            app.StateorRegionLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.Covid19DataVisualizationDataSourceUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.AveragedofdaysSliderLabel = uilabel(app.Covid19DataVisualizationDataSourceUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.Covid19DataVisualizationDataSourceUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.Covid19DataVisualizationDataSourceUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.Covid19DataVisualizationDataSourceUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.UIAxes = uiaxes(app.Covid19DataVisualizationDataSourceUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.Covid19DataVisualizationDataSourceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project_Mastering_Programming_with_MATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19DataVisualizationDataSourceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19DataVisualizationDataSourceUIFigure)
        end
    end
end