classdef Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationDataSourceUIFigure  matlab.ui.Figure
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Class
        countries = {};
        Cnames = {'Global'};
        
        % Plot
        dates
        title
        
        current_case
        current_death
        
        average_case
        average_death
        
        daily_case
        daily_death
        
        total_case = 0;
        total_death = 0;
        
        % Mode
        case_death_both = 1;
        daily = false;    
        
    end
    
    methods (Access = private)
        
        function initialization(app)
            % initialize CountryListBox
            app.CountryListBox.Items = app.Cnames;
            app.CountryListBox.Value = "Global";
            
            % initialize StateorRegionListBox
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.Value = "All";
            
            % initialize current/average values
            app.current_case = app.total_case;
            app.average_case = app.total_case;
            app.current_death = app.total_death;
            app.average_death = app.total_death;
        end
        
        function computeAverage(app)
            % compute daily case/death
            if app.daily
                case_before = [0 app.current_case(1:end-1)];
                app.daily_case = app.current_case - case_before;
                death_before = [0 app.current_death(1:end-1)];
                app.daily_death = app.current_death - death_before;
            end
            
            % compute average case/death
            step = round(app.AveragedofdaysSlider.Value);
            
            if step == 1 && app.daily
                app.average_case = app.daily_case;
                app.average_death = app.daily_death;
            elseif step == 1
                app.average_case = app.current_case;
                app.average_death = app.current_death;
            elseif app.daily
                for i = 1 : length(app.average_case)
                    start = max(1,i-step+1);
                    app.average_case(i) = mean(app.daily_case(start:i));
                    app.average_death(i) = mean(app.daily_death(start:i));
                end
            else
                for i = 1 : length(app.average_case)
                    start = max(1,i-step+1);
                    app.average_case(i) = mean(app.current_case(start:i));
                    app.average_death(i) = mean(app.current_death(start:i));
                end
            end
        end 
        
        function plotData(app,case_death_both)
            % start, clear the figure
            hold(app.UIAxes,'on');
            cla(app.UIAxes,"reset");           
            
            % create title
            app.createTitle();
            
            % plot according to case_death_both
            if case_death_both == 1
                bar(app.UIAxes,app.dates,app.average_case,'blue');
                app.UIAxes.XTickLabel = string(app.UIAxes.XTick,"dd/MM");
                app.UIAxes.YTickLabel = string(app.UIAxes.YTick);
                title(app.UIAxes,app.title);
                grid(app.UIAxes,"on");
            elseif case_death_both == 2
                plot(app.UIAxes,app.dates,app.average_death,'-r');
                app.UIAxes.XTickLabel = string(app.UIAxes.XTick,"dd/MM");
                app.UIAxes.YTickLabel = string(app.UIAxes.YTick);
                title(app.UIAxes,app.title);
                grid(app.UIAxes,"on");
            else    
                yyaxis(app.UIAxes,"left")
                bar(app.UIAxes,app.dates,app.average_case,'blue');
                app.UIAxes.XTickLabel = string(app.UIAxes.XTick,"dd/MM");
                app.UIAxes.YTickLabel = string(app.UIAxes.YTick);
                yyaxis(app.UIAxes,"right")
                plot(app.UIAxes,app.dates,app.average_death,'-r');
                app.UIAxes.XTickLabel = string(app.UIAxes.XTick,"dd/MM");
                app.UIAxes.YTickLabel = string(app.UIAxes.YTick);
                title(app.UIAxes,app.title);
                grid(app.UIAxes,"on");
            end
            
            % end
            hold(app.UIAxes,'off');
        end  
        
        function createTitle(app)
            app.title = "Number of ";
            
            % cumulative or daily?
            if app.daily
                app.title = "Daily " + app.title;
            else
                app.title = "Cumulative " + app.title;
            end
            
            % cases, deaths or both?
            if app.case_death_both == 1
                app.title = app.title + "Cases in ";
            elseif app.case_death_both == 2
                app.title = app.title + "Deaths in ";
            else
                app.title = app.title + "Cases and Deaths in ";
            end
            
            % country or state?
            if app.StateorRegionListBox.Value == "All"
                app.title = app.title + app.CountryListBox.Value;
            else
                app.title = app.title + app.StateorRegionListBox.Value;
            end
            
            % average?
            if round(app.AveragedofdaysSlider.Value) ~= 1
                app.title = app.title + " (" + round(app.AveragedofdaysSlider.Value) + "-day mean)";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load data
            load 'covid_data.mat'
            
            % initialize dates
            app.dates = covid_data(1,3:end);
            app.dates = string(app.dates);
            app.dates = datetime(app.dates,'InputFormat','MM/dd/yy');
            
            % create classes            
            for i = 2:length(covid_data)
                Cname = covid_data{i,1};                     % country name
                C_name = regexprep(Cname,'\W','_');          % country class name
                Sname = covid_data{i,2};                     % state name
                S_name = regexprep(Sname,'\W','_');          % state class name
                
                count = [covid_data{i,3:end}];               % current case + death
                cc = count(1:2:end);                         % current case
                dc = count(2:2:end);                         % current death
                
                if S_name == ""
                    app.Cnames{end+1} = Cname;               % save country name
                    app.total_case = app.total_case + cc;    % compute total case
                    app.total_death = app.total_death + dc;  % compute total death
                    eval([C_name,'=Country(Cname,cc,dc)']);  % create country class
                    eval(['app.countries{end+1}=',C_name]);  % add country class into the countries
                else
                    eval([C_name,'.Snames{end+1}=Sname']);   % save state name
                    eval([S_name,'=State(Sname,cc,dc)']);    % create state class
                    eval([C_name,'.States{end+1}=',S_name]); % add state class into country class
                end
            end
            
            % initialization
            app.initialization();  
            
            % plot data
            app.plotData(1);
        end

        % Value changed function: CountryListBox
        function Country_ListBox_ValueChanged(app, event)
            % set state to "All"
            app.StateorRegionListBox.Value = app.StateorRegionListBox.Items(1);
            
            % update current case/death
            value = app.CountryListBox.Value;
            if value == "Global"
                app.StateorRegionListBox.Items = {'All'};
                app.current_case = app.total_case;
                app.current_death = app.total_death;
            else
                p = find(strcmp(app.Cnames(2:end),value));
                app.StateorRegionListBox.Items = app.countries{p}.Snames;
                app.current_case = app.countries{p}.CaseCount;
                app.current_death = app.countries{p}.DeathCount;
            end
            
            % re-compute Average
            app.computeAverage();
            
            % plot data
            app.plotData(app.case_death_both);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegion_ListBox_ValueChanged(app, event)
            % update current case/death
            value = app.StateorRegionListBox.Value;
            country = app.CountryListBox.Value;
            pc = find(strcmp(app.Cnames(2:end),country));
            
            if value == "All"
                app.current_case = app.countries{pc}.CaseCount;
                app.current_death = app.countries{pc}.DeathCount;
            else
                ps = find(strcmp(app.countries{pc}.Snames(2:end),value));
                app.current_case = app.countries{pc}.States{ps}.CaseCount;
                app.current_death = app.countries{pc}.States{ps}.DeathCount;
            end
            
            % re-compute Average
            app.computeAverage();
            
            % plot data
            app.plotData(app.case_death_both);
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlot_ButtonGroup_SelectionChanged(app, event)
            % update case_death_both
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            switch selectedButton
                case app.CasesButton
                    app.case_death_both = 1;
                case app.DeathsButton
                    app.case_death_both = 2;
                otherwise
                    app.case_death_both = 3;
            end
            
            % plot data
            app.plotData(app.case_death_both);
        end

        % Selection changed function: OptionButtonGroup
        function Option_ButtonGroup_SelectionChanged(app, event)
            % update daily
            selectedButton = app.OptionButtonGroup.SelectedObject;
            if selectedButton == app.DailyButton
                app.daily = true;
            else
                app.daily = false;
            end
            
            % re-compute Average
            app.computeAverage();
            
            % plot data
            app.plotData(app.case_death_both);
        end

        % Value changed function: AveragedofdaysSlider
        function Averagedofdays_Slider_ValueChanged(app, event)
            % re-compute Average
            app.computeAverage();
            
            % plot data
            app.plotData(app.case_death_both);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationDataSourceUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationDataSourceUIFigure.Name = 'COVID-19 Data Visualization (Data Source: Johns Hopkins University)';
            app.CountryListBoxLabel = uilabel(app.COVID19DataVisualizationDataSourceUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationDataSourceUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @Country_ListBox_ValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionListBoxLabel = uilabel(app.COVID19DataVisualizationDataSourceUIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.COVID19DataVisualizationDataSourceUIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegion_ListBox_ValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.AveragedofdaysSliderLabel = uilabel(app.COVID19DataVisualizationDataSourceUIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.COVID19DataVisualizationDataSourceUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @Averagedofdays_Slider_ValueChanged, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationDataSourceUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlot_ButtonGroup_SelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationDataSourceUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Option_ButtonGroup_SelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationDataSourceUIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.COVID19DataVisualizationDataSourceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationDataSourceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationDataSourceUIFigure)
        end
    end
end