classdef Covid_App_New < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionsButtonGroup         matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DefaultSettingsButton      matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xData
        Cases_Deaths_Matrix
        CaseVec
        DeathVec
        AveragesCases
        AveragesDeath
        Averagingwindow
        Int_title %The name of Country or the state in a Country
        tav% Infos about the averging
        SearchCountry
        SearchState
    end
    methods (Access = private)
        
        function  PlotData(app)
           
            app.UIAxes.reset;


           datetick(app.UIAxes,'x','mm/dd','keeplimits');%transfer ticklabel for c-axes from date serialnumber to the normal date
           
            
            app.UIAxes.Color = [0.8 0.8 0.8];
            grid(app.UIAxes,'on');
            
           
           ta=int8('Cumulative Number of Cases in ');
           tb=int8('cumulative Number of Deaths in ');
           tc=int8('cumulative Number of Cases and Deaths in ');
           td=int8('Daily Number of Cases  in ');
           te=int8('Daily Number of  Deaths in ');
           tf=int8('Daily Number of Cases and Deaths in ');
           
           ta2=int8('Cumulative Number of Cases Globally ');
           tb2=int8('cumulative Number of Deaths Globally ');
           tc2=int8('cumulative Number of Cases and Deaths Globally ');
           td2=int8('Daily Number of Cases  Globally ');
           te2=int8('Daily Number of  Deaths Globally ');
           tf2=int8('Daily Number of Cases and Deaths Globally ');
           
           
           if app.CasesButton.Value && app.CumulativeButton.Value
               app.CumulativeVec();
               app.ComputeAverage();
               bar(app.UIAxes,app.xData,app.AveragesCases,'blue');
               [~,in1]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
               if in1==1
                 Num_Title=[ta2 app.tav];  
                 title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               else
               Num_Title=[ta app.Int_title app.tav];
               title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               end
           elseif app.CasesButton.Value && app.DailyButton.Value
               app.DailyVec();
               app.ComputeAverage();
               bar(app.UIAxes,app.xData,app.AveragesCases,'blue');
               [~,in1]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
               if in1==1
                 Num_Title=[td2 app.tav];  
                 title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               else
               Num_Title=[td app.Int_title app.tav];
               title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               end
           elseif app.DeathsButton.Value && app.CumulativeButton.Value
               app.CumulativeVec();
               app.ComputeAverage();
               plot(app.UIAxes,app.xData,app.AveragesDeath,'r')
               [~,in1]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
               if in1==1
                 Num_Title=[tb2 app.tav];  
                 title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               else
               Num_Title=[tb app.Int_title app.tav];
               title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               end
           elseif app.DeathsButton.Value && app.DailyButton.Value
               app.DailyVec();
               app.ComputeAverage;
               plot(app.UIAxes,app.xData,app.AveragesDeath,'r')
               [~,in1]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
               if in1==1
                 Num_Title=[te2 app.tav];  
                 title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               else
               Num_Title=[te app.Int_title app.tav];
               title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               end
           elseif app.BothButton.Value && app.CumulativeButton.Value
               app.CumulativeVec();
               app.ComputeAverage;
               yyaxis(app.UIAxes,'left');
               bar(app.UIAxes,app.xData,app.AveragesCases,'blue');
               app.UIAxes.YColor=[0.02 0.20 0.73];app.UIAxes.YLimMode='auto';app.UIAxes.YTickMode='auto';
               hold(app.UIAxes,'on');
               yyaxis(app.UIAxes,'right');
               plot(app.UIAxes,app.xData,app.AveragesDeath,'r');
               app.UIAxes.YColor=[0.63 0.12 0.09];app.UIAxes.YLimMode='auto';app.UIAxes.YTickMode='auto';
               [~,in1]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
               if in1==1
                 Num_Title=[tc2 app.tav];  
                 title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               else
               Num_Title=[tc app.Int_title app.tav];
               title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               end
            elseif app.BothButton.Value && app.DailyButton.Value
               app.DailyVec();
               app.ComputeAverage;
               yyaxis(app.UIAxes,'left');
               bar(app.UIAxes,app.xData,app.AveragesCases,'blue');
               app.UIAxes.YColor=[0.02 0.20 0.73];app.UIAxes.YLimMode='auto';app.UIAxes.YTickMode='auto';
               hold(app.UIAxes,'on');
               yyaxis(app.UIAxes,'right');
               plot(app.UIAxes,app.xData,app.AveragesDeath,'r')
               app.UIAxes.YColor=[0.63 0.12 0.09];app.UIAxes.YLimMode='auto';app.UIAxes.YTickMode='auto';
               [~,in1]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
               if in1==1
                 Num_Title=[tf2 app.tav];  
                 title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               else
               Num_Title=[tf app.Int_title app.tav];
               title(app.UIAxes,char(Num_Title),"HorizontalAlignment","center");
               end
           end
            
        end


        function ComputeAverage(app) 
            app.AveragesCases=movmean(app.CaseVec,app.Averagingwindow);%Compute the three-point centered moving average of a row vector. When there are fewer than 30 elements in the window at the endpoints, take the average over the elements that are available.
            app.AveragesDeath=movmean(app.DeathVec,app.Averagingwindow);
        end
        %Numbers for every Day
        function DailyVec(app)
            app.CumulativeVec();% the same, but we have now to compute just daily cases and deathes
            %Making the Vector of Deaths Daily 
            x=app.CaseVec-circshift(app.CaseVec,1);%That is much better than a loop
            x(1)=app.CaseVec(1);%the first elemt of the vector must bes the same and not (FirstItem-LastItem)
            k=logical(x<0);%To prevent that the number is less than 0 
            x(k)=0;
            app.CaseVec=x;% The new CaseVec
            %Making the Vector of Deaths Daily
            y=app.DeathVec-circshift(app.DeathVec,1);
            y(1)=app.DeathVec(1);
            g=logical(y<0);
            y(g)=0;
            app.DeathVec=y;
                
            
        end
        
        function CumulativeVec(app)
            Item_Selected=Data(app.CountryListBox.Value);%Loading the Data with the selected Country
            [~,in1]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);%The index of the selected Country
            [~,in2]=ismember(app.StateorRegionListBox.Value,app.StateorRegionListBox.Items);%The index of the selected State
            if in1==1 %Global situation
              app.Cases_Deaths_Matrix=Item_Selected.Global_Cases_And_Deaths; 
              app.CaseVec=app.Cases_Deaths_Matrix(1,:);
              app.DeathVec=app.Cases_Deaths_Matrix(2,:);  
            else%Another Situations (we use the function Case_Vector and Death_Vector from our Data)
                app.CaseVec=Item_Selected.Case_Vector(Item_Selected.state_index(in2));% With in2 we can determine the index of state from the vector (state_index) in our Data
                app.DeathVec=Item_Selected.Death_Vector(Item_Selected.state_index(in2));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      GlobalData=Data('Global');%Loading our class (Data) with the Global option (index is 1 and State is All) and with all other Properties
      app.CountryListBox.Items=(unique(GlobalData.Country,'stable'));%returns the same data as in the vector "Country", but with no repetitions. Stable means that the order stay like in the vector
      app.StateorRegionListBox.Items=(unique(GlobalData.state,'stable'));   
      app.CountryListBox.Value='Global';%Higliting of the first item of this list "Global"
      app.Cases_Deaths_Matrix=GlobalData.Global_Cases_And_Deaths; 
      app.CaseVec=app.Cases_Deaths_Matrix(1,:);
      app.DeathVec=app.Cases_Deaths_Matrix(2,:);
      StartDate=datenum(GlobalData.Date{1});% the function datenum is to convert the datetime  values in the input array t to serial date numbers.  
      EndDate=datenum(GlobalData.Date{end});
      app.xData=linspace(StartDate,EndDate,length(GlobalData.Date));
      
      app.Averagingwindow=app.AveragedofdaysSlider.Value;
      %app.StateorRegionListBoxValueChanged;


      %Call the PlotData Function
      
       app.PlotData();   
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
           
            Country_selected=Data(app.CountryListBox.Value);
            app.StateorRegionListBox.Items=(unique(Country_selected.state,'stable'));           
            %Call the function of the other listBox
            app.StateorRegionListBoxValueChanged;
            
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            %Defination of Int_title 
            [~,in2]=ismember(app.StateorRegionListBox.Value,app.StateorRegionListBox.Items);
            if in2==1
                app.Int_title=app.CountryListBox.Value;
            else
                app.Int_title=app.StateorRegionListBox.Value;
            end
                
                
            %Call the Plot function
            app.PlotData();
            
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            %Call the plot function
            app.PlotData();
            
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
             %Call the plot function
             app.PlotData();
            
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
               app.Averagingwindow = event.Value;
            if app.Averagingwindow~=1
                tav1=int8(' (');tav2=int8('-day mean)');
                app.tav=[tav1,int8(char(string(round(app.Averagingwindow)))),tav2];
            else
                app.tav=int8(' ');
            end
            
            %Call the plot function
            app.PlotData();
            
        end

        % Button pushed function: DefaultSettingsButton
        function DefaultSettingsButtonPushed(app, event)
         app.AveragedofdaysSlider.Value=app.AveragedofdaysSlider.Limits(1);
         GlobalData=Data('Global');
         app.CountryListBox.Items=(unique(GlobalData.Country,'stable'));
         app.StateorRegionListBox.Items=(unique(GlobalData.state,'stable'));
         app.CountryListBox.Value='Global';
         app.CountryListBox.scroll('top')
         app.StateorRegionListBox.scroll('top')
         app.CasesButton.Value=true;
         app.CumulativeButton.Value=true;
         app.DeathsButton.Value=false;
         app.BothButton.Value=false;
         app.DailyButton.Value=false;
         yyaxis(app.UIAxes,'right');
         app.UIAxes.reset;
         
         %Set the properties
         app.Averagingwindow=app.AveragedofdaysSlider.Value;
         
         %Call the function
         
         app.PlotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Covid-19 Data Visualization(Source: Johns Hopkins University)';
            app.UIFigure.Icon = 'Icon.png';
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged of days';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DefaultSettingsButton = uibutton(app.UIFigure, 'push');
            app.DefaultSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSettingsButtonPushed, true);
            app.DefaultSettingsButton.Text = 'Default Settings';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cumulative Number of Casses Globally')
            xlabel(app.UIAxes, 'Months (January to October) in 2020')
            ylabel(app.UIAxes, 'Number of people')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid_App_New

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end