classdef covid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateofRegionListBoxLabel  matlab.ui.control.Label
        StateofRegionListBox       matlab.ui.control.ListBox
        AveragedofdaysLabel        matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Country_list_default % Description
        State_list_country
        vector_countries    Country
        qty_countries       uint8 %length of vector countries, including global
        dates              
        Xpoints             single
        Xticks              single
        Global_Cases_acum   uint32
        Global_Deaths_acum  uint32
        Global_Cases_daily  uint32
        Global_Deaths_daily uint32
        current_country     uint8
        current_state       uint8
        Option              char
        Data_to_plot        char
        Av_days             uint8
    end
    
    methods (Access = private)
        
        function plot_grafica(app,state_obj)
            app.UIAxes.YLimMode = 'auto';
            yyaxis(app.UIAxes,'right');
            app.UIAxes.YAxis(2).TickLabelFormat = '%d';
            app.UIAxes.YAxis(1).TickLabelFormat = '%d';
            if isequal(app.Data_to_plot,'C')
                [y,~] =state_obj.get_cases_and_deaths(app.Option);
                y = modify_data(app,y);
                yyaxis(app.UIAxes,'right');
                cla(app.UIAxes);
                yyaxis(app.UIAxes,'left');
                app.UIAxes.YAxis(2).Visible='off'; %remove right
                app.UIAxes.YAxis(1).Visible='on'; %insert left
                bar(app.UIAxes,app.Xpoints,y,'FaceColor','#75e561');
                app.UIAxes.XTick = app.Xticks;
                if max(y)<5
                    app.UIAxes.YAxis(1).TickLabelFormat = '%.1f';
                end
            elseif isequal(app.Data_to_plot,'D')
                [~,y] =state_obj.get_cases_and_deaths(app.Option);
                y = modify_data(app,y);
                yyaxis(app.UIAxes,'left');
                cla(app.UIAxes);
                yyaxis(app.UIAxes,'right');
                app.UIAxes.YAxis(1).Visible='off'; %remove left
                app.UIAxes.YAxis(2).Visible='on'; %insert right
                plot(app.UIAxes,app.Xpoints,y,'Color','#e8606a','LineWidth',2);
                app.UIAxes.XTick = app.Xticks;
                if isequal(zeros(1,length(y)),y)
                    app.UIAxes.YLim = [0,5]; 
                elseif max(y)<5
                    app.UIAxes.YAxis(2).TickLabelFormat = '%.1f';
                end
            elseif isequal(app.Data_to_plot,'CaD')
                [y1,y2]= state_obj.get_cases_and_deaths(app.Option);
                y1 = modify_data(app,y1);
                y2 = modify_data(app,y2);
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.Xpoints,y1,0.8,'FaceColor','#75e561');
                app.UIAxes.XTick = app.Xpoints;
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.Xpoints,y2,'Color','#E8606A','LineWidth',2);
                app.UIAxes.XTick = app.Xticks;
                app.UIAxes.YAxis(1).Visible='on'; %insert left
                app.UIAxes.YAxis(2).Visible='on'; %insert right
                if isequal(zeros(1,length(y2)),y2)
                    app.UIAxes.YLim = [0,5]; 
                elseif max(y2)<5
                    app.UIAxes.YAxis(2).TickLabelFormat = '%.1f';
                end
                if max(y1)<5
                    app.UIAxes.YAxis(1).TickLabelFormat = '%.1f';
                end
            end

            %Set title
            if isequal(app.Option,'D')
                app.UIAxes.Title.String ="Daily";
            else
                app.UIAxes.Title.String ="Cumulative";
            end
            app.UIAxes.Title.String =app.UIAxes.Title.String +" Number of ";
            if isequal(app.Data_to_plot,'C')
                app.UIAxes.Title.String =app.UIAxes.Title.String +"Cases";
            elseif isequal(app.Data_to_plot,'D')
                app.UIAxes.Title.String =app.UIAxes.Title.String +"Deaths";
            else
                app.UIAxes.Title.String =app.UIAxes.Title.String +"Cases and Deaths";
            end
            if app.current_country ==1
                app.UIAxes.Title.String =app.UIAxes.Title.String +" Globally";
            else
                app.UIAxes.Title.String =app.UIAxes.Title.String +" in ";
                if app.current_state ==1
                    app.UIAxes.Title.String =app.UIAxes.Title.String +string(app.CountryListBox.Items{app.current_country});
                else
                    app.UIAxes.Title.String =app.UIAxes.Title.String +string(app.StateofRegionListBox.Items{app.current_state});
                end
            end            
            if app.Av_days >1
                app.UIAxes.Title.String =app.UIAxes.Title.String +" ("+string(app.Av_days)+"-days mean)";
            end
            app.UIAxes.Title.String = char("\color{white}"+app.UIAxes.Title.String);
        end
        
        function All_cases_and_deaths(app)
            cases_acum = zeros(1,length(app.dates));
            deaths_acum =cases_acum;
            cases_daily =cases_acum;
            deaths_daily =cases_acum;
            for i=2:app.qty_countries
                country_list = app.vector_countries(i);
                for j =1:length(country_list)
                    state_obj=country_list.get_state_obj(j);
                    [temp1,temp2] =state_obj.get_cases_and_deaths('A');
                    cases_acum = cases_acum +temp1;
                    deaths_acum = deaths_acum+ temp2;
                    [temp1,temp2] =state_obj.get_cases_and_deaths('D');
                    cases_daily = cases_daily +temp1;
                    deaths_daily = deaths_daily+ temp2;
                end
            end
            app.Global_Cases_acum = cases_acum;
            app.Global_Deaths_acum = deaths_acum;            
            app.Global_Cases_daily = cases_daily;
            app.Global_Deaths_daily = deaths_daily;            
        end
        
        
        function new_y = modify_data(app,y)
            new_y = zeros(1,length(y));
            for i= 1:length(y)
                if i>=app.Av_days
                    new_y(i) = mean (y(i+1-double(app.Av_days):i));
                else
                    new_y(i)=mean(y(1:i));
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat;
            data = covid_data;
            [row,col]=size(data);
            app.Data_to_plot = 'CaD';
            app.Option = 'A';
            app.current_country =1;
            app.current_state = 1;
            app.Av_days =uint8(app.AveragedofdaysSlider.Value);
            
            %dates mm/dd/yy
            app.dates = cell(1,col-2);
            for i=3:col
                app.dates{1,i-2}=data{1,i};
                app.Xpoints(1,i-2)= (i-2)/10;
            end
            
            temp_date = strsplit(app.dates{1,1},'/');
            temp_day = str2double(temp_date{2});
            app.UIAxes.XTickLabel {1}= char(temp_date{1}+"/"+temp_date{2});
            temp_index = 1;
            while (temp_day>28)
                temp_index = temp_index+1;
                temp_date = strsplit(app.dates{1,temp_index},'/');
                temp_day = str2double(temp_date{2});
                app.UIAxes.XTickLabel {1}= char(temp_date{1}+"/"+temp_date{2});
            end
            app.Xticks (1,1)= temp_index/10;
            temp_index=temp_index+1;
            temp_common_day = temp_day;
            temp_index_ticks = 1;
            for i=temp_index:col-2
                temp_date = strsplit(app.dates{1,i},'/');
                temp_day = str2double(temp_date{2});
                if temp_day==temp_common_day
                    temp_index_ticks=temp_index_ticks+1;
                    app.UIAxes.XTickLabel {temp_index_ticks}= char(temp_date{1}+"/"+temp_date{2});
                    app.Xticks (1,temp_index_ticks)=i/10;
                end
            end
            
            %vector of countries
            row=row+1;
            app.vector_countries(row)= Country('default'); %preallocate
            app.vector_countries(1)= Country('Global');
            app.vector_countries(1).insert(State('All'));
            app.qty_countries =1;
            for i=2:row-1
                if ~isequal(data{i},app.vector_countries(app.qty_countries).get_country_name)
                    %Nuevo pais
                    app.qty_countries=app.qty_countries+1;
                    app.vector_countries(app.qty_countries)= Country(data{i});
                    app.vector_countries(app.qty_countries).insert(State('All',data(i,3:end)));
                else
                    %Mismo país, distinto estado
                    app.vector_countries(app.qty_countries).insert(State(data{i,2},data(i,3:end)));
                end
            end
            app.vector_countries = app.vector_countries(1:app.qty_countries);
            
            All_cases_and_deaths(app);
            app.vector_countries(1).get_state_obj(1).Cases_acum=app.Global_Cases_acum;
            app.vector_countries(1).get_state_obj(1).Deaths_acum=app.Global_Deaths_acum;
            app.vector_countries(1).get_state_obj(1).Cases_daily=app.Global_Cases_daily;
            app.vector_countries(1).get_state_obj(1).Deaths_daily=app.Global_Deaths_daily;
            
            %inicializamos los items de country
            for i=1:app.qty_countries
                app.CountryListBox.Items {i}= char(app.vector_countries(i).get_country_name);                
            end
            app.Country_list_default = app.CountryListBox.Items;
            %inicializamos los items de state
            app.StateofRegionListBox.Items ={'ALL'};
            app.StateofRegionListBox.Value = 'ALL';
            
            %plot Global cases
            app.UIAxes.YAxis.Exponent = 0;
            app.UIAxes.YAxis.TickLabelFormat = '%d';
            plot_grafica(app,app.vector_countries(1).get_state_obj(1)); %plot global
            app.UIAxes.YAxis(2).Exponent = 0;
            app.UIAxes.YAxis(2).TickLabelFormat = '%d';
            app.UIAxes.YAxis(1).Color = '#75e561';
            app.UIAxes.YAxis(2).Color = '#e8606a';
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = string(app.CountryListBox.Value);
            %se puede mejorar, con el find, similar al de State
            app.current_country = find(strcmp(app.CountryListBox.Items, value));
            app.current_state=1;
            app.StateofRegionListBox.Items =app.vector_countries(app.current_country).get_states;
            plot_grafica(app,app.vector_countries(app.current_country).get_state_obj(1));
        end

        % Value changed function: StateofRegionListBox
        function StateofRegionListBoxValueChanged(app, event)
            value = string(app.StateofRegionListBox.Value);
            app.current_state = find(strcmp(app.StateofRegionListBox.Items, value));
            
            plot_grafica(app,app.vector_countries(app.current_country).get_state_obj(app.current_state));
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            %selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            if app.CasesButton.Value 
                app.Data_to_plot = 'C';
                plot_grafica(app,app.vector_countries(app.current_country).get_state_obj(app.current_state));
            elseif app.DeathsButton.Value
                app.Data_to_plot = 'D';
                plot_grafica(app,app.vector_countries(app.current_country).get_state_obj(app.current_state));
            elseif app.BothButton.Value
                app.Data_to_plot = 'CaD';
                plot_grafica(app,app.vector_countries(app.current_country).get_state_obj(app.current_state));
            end
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            %selectedButton = app.OptionButtonGroup.SelectedObject;
            if app.CumulativeButton.Value
                app.Option='A';
                plot_grafica(app,app.vector_countries(app.current_country).get_state_obj(app.current_state));
            elseif app.DailyButton.Value
                app.Option='D';
                plot_grafica(app,app.vector_countries(app.current_country).get_state_obj(app.current_state));
            end
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.Av_days = int8(floor(app.AveragedofdaysSlider.Value));%transfor to integer
            plot_grafica(app,app.vector_countries(app.current_country).get_state_obj(app.current_state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'not value'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = 'not value';
            app.StateofRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateofRegionListBoxLabel.Text = 'State of Region';
            app.StateofRegionListBox = uilistbox(app.UIFigure);
            app.StateofRegionListBox.Items = {'not value'};
            app.StateofRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateofRegionListBoxValueChanged, true);
            app.StateofRegionListBox.Value = 'not value';
            app.AveragedofdaysLabel = uilabel(app.UIFigure);
            app.AveragedofdaysLabel.Text = {'Averaged '; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [1.4 1.8 2.2 2.6 3.4 3.8 4.2 4.6 5.4 5.8 6.2 6.6 7.4 7.8 8.2 8.6 9.4 9.8 10.2 10.6 11.4 11.8 12.2 12.6 13.4 13.8 14.2 14.6 15.4 15.8 16.2 16.6];
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; ''; ''; ''; ''; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end